classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        EmulatorTab                    matlab.ui.container.Tab
        EmulatorTabGridLayout          matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        EmulatorControlsLayout         matlab.ui.container.GridLayout
        EmulatorResetButton            matlab.ui.control.Button
        CPUspeedKnobLabel              matlab.ui.control.Label
        EmulatorCpuSpeedKnob           matlab.ui.control.Knob
        EmulatorDebuggerKnob           matlab.ui.control.DiscreteKnob
        DebuggerKnobLabel              matlab.ui.control.Label
        AssemblerTab                   matlab.ui.container.Tab
        AssemblerTabGridLayout         matlab.ui.container.GridLayout
        AssemblerEditorGridLayout      matlab.ui.container.GridLayout
        AssemblerFileStatusGridLayout  matlab.ui.container.GridLayout
        AssemblerSaveFileButton        matlab.ui.control.Button
        AssemblerFileNameLabel         matlab.ui.control.Label
        AssemblerFileBrowserLabel      matlab.ui.control.Label
        AssemblerFileBrowser           matlab.ui.container.Tree
        BuiltInExamplesNode            matlab.ui.container.TreeNode
        BuiltInExamplesHelloWorldNode  matlab.ui.container.TreeNode
        LocalFilesystemNode            matlab.ui.container.TreeNode
        LocalFilesystemTempNode        matlab.ui.container.TreeNode
        AssemblerEditor                matlab.ui.control.TextArea
        GridLayout5                    matlab.ui.container.GridLayout
        AssemblerStatusLabel           matlab.ui.control.Label
        AssemblerBurnButton            matlab.ui.control.Button
        ProfilerTab                    matlab.ui.container.Tab
        ProfilerTabGridLayout          matlab.ui.container.GridLayout
        ProfilerTabControlsGridLayout  matlab.ui.container.GridLayout
        PauseProfilerButton            matlab.ui.control.Button
        ProfilerDurationDropDown       matlab.ui.control.DropDown
        DisplaythelastDropDownLabel    matlab.ui.control.Label
        ResetProfilerButton            matlab.ui.control.Button
        ProfilerFigure                 matlab.ui.control.UIAxes
        SettingsTab                    matlab.ui.container.Tab
        SettingsTabContainer           matlab.ui.container.GridLayout
        SettingsSubtabGroup            matlab.ui.container.TabGroup
        TerminalAppearanceTab          matlab.ui.container.Tab
        TerminalAppearanceGridLayout   matlab.ui.container.GridLayout
        BackColorGridLayout            matlab.ui.container.GridLayout
        BackLamp                       matlab.ui.control.Lamp
        BackColorSliderGridLayout      matlab.ui.container.GridLayout
        BackBlueSlider                 matlab.ui.control.Slider
        BlueSlider_2Label              matlab.ui.control.Label
        BackGreenSlider                matlab.ui.control.Slider
        GreenSlider_2Label             matlab.ui.control.Label
        BackRedSlider                  matlab.ui.control.Slider
        RedSlider_2Label               matlab.ui.control.Label
        TextColorGridLayout            matlab.ui.container.GridLayout
        TextLamp                       matlab.ui.control.Lamp
        TextColorSliderGridLayout      matlab.ui.container.GridLayout
        TextRedSlider                  matlab.ui.control.Slider
        RedSliderLabel                 matlab.ui.control.Label
        BlueSliderLabel                matlab.ui.control.Label
        TextBlueSlider                 matlab.ui.control.Slider
        TextGreenSlider                matlab.ui.control.Slider
        GreenSliderLabel               matlab.ui.control.Label
        BackColorLabel                 matlab.ui.control.Label
        TextColorLabel                 matlab.ui.control.Label
        MIDISetupTab                   matlab.ui.container.Tab
        MIDISetupGridLayout            matlab.ui.container.GridLayout
        MIDISetupLabel                 matlab.ui.control.Label
        AboutTab                       matlab.ui.container.Tab
        AboutTabGridLayout             matlab.ui.container.GridLayout
        AboutLicenseLabel              matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        AboutMATLABLicenseButton       matlab.ui.control.Button
        AboutGPLButton                 matlab.ui.control.Button
        AboutLicenseLogo               matlab.ui.control.Image
        AboutDescGridLayout            matlab.ui.container.GridLayout
        AboutDescLabel                 matlab.ui.control.Label
        AboutNameLabel                 matlab.ui.control.Label
        AboutLogo                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EmulatorStart(app)
            i = 0;
            
            while true
                drawnow limitrate
                app.Terminal.Value = string(i);
                i = i + 1;
            end
        end

        % Callback function
        function TerminalColourChange(app, event)
            bg = [ ...
                app.BackRedSlider.Value / 100 ...
                app.BackGreenSlider.Value / 100 ...
                app.BackBlueSlider.Value / 100 ...
            ];
            
            fg = [ ...
                app.TextRedSlider.Value / 100 ...
                app.TextGreenSlider.Value / 100 ...
                app.TextBlueSlider.Value / 100 ...
            ];
            
            app.BackLamp.Color = bg;
            app.TextLamp.Color = fg;

            app.Terminal.FontColor = fg;
            app.Terminal.BackgroundColor = bg;
        end

        % Key press function: MainFigure
        function MainFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Button pushed function: AboutMATLABLicenseButton
        function AboutMATLABLicenseButtonPushed(app, event)
            
        end

        % Button pushed function: AboutGPLButton
        function AboutGPLButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'A dubiously accurate Apple I emulator';
            app.MainFigure.KeyPressFcn = createCallbackFcn(app, @MainFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.EmulatorTab = uitab(app.TabGroup);
            app.EmulatorTab.Title = 'Emulator';
            app.EmulatorTabGridLayout = uigridlayout(app.EmulatorTab);
            app.EmulatorControlsLayout = uigridlayout(app.EmulatorTabGridLayout);
            app.DebuggerKnobLabel = uilabel(app.EmulatorControlsLayout);
            app.DebuggerKnobLabel.Text = 'Debugger';
            app.EmulatorDebuggerKnob = uiknob(app.EmulatorControlsLayout, 'discrete');
            app.EmulatorDebuggerKnob.Items = {'Off', 'On'};
            app.EmulatorCpuSpeedKnob = uiknob(app.EmulatorControlsLayout, 'continuous');
            app.EmulatorCpuSpeedKnob.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.EmulatorCpuSpeedKnob.Value = 100;
            app.CPUspeedKnobLabel = uilabel(app.EmulatorControlsLayout);
            app.CPUspeedKnobLabel.Text = 'CPU speed';
            app.EmulatorResetButton = uibutton(app.EmulatorControlsLayout, 'push');
            app.EmulatorResetButton.Text = 'RESET SYSTEM';
            app.GridLayout8 = uigridlayout(app.EmulatorTabGridLayout);
            app.AssemblerTab = uitab(app.TabGroup);
            app.AssemblerTab.Title = 'Assembler';
            app.AssemblerTabGridLayout = uigridlayout(app.AssemblerTab);
            app.GridLayout5 = uigridlayout(app.AssemblerTabGridLayout);
            app.AssemblerBurnButton = uibutton(app.GridLayout5, 'push');
            app.AssemblerBurnButton.Text = 'Write to memory';
            app.AssemblerStatusLabel = uilabel(app.GridLayout5);
            app.AssemblerStatusLabel.Text = 'The assembler is initializing, please wait..';
            app.AssemblerEditorGridLayout = uigridlayout(app.AssemblerTabGridLayout);
            app.AssemblerEditor = uitextarea(app.AssemblerEditorGridLayout);
            app.AssemblerEditor.Value = {'; Set the program origin to address $1000.'; ''; '* = $1000'; ''; '; Some exported functions provided by WOZMON.'; ''; 'WOZMON_OUT = $FFEF'; 'WOZMON_INIT = $FF00'; ''; '; Main program entry.'; ''; 'start:'; '	; Print a string and exit back to WOZMON.'; ''; '	lda #<init_msg'; '	sta $00'; '	lda #>init_msg'; '	sta $01'; ''; '	jsr print_string'; ''; 'done:'; '	jmp WOZMON_INIT'; ''; '; Will print a zero-terminated string pointed to'; '; by $00/$01 (low/high byte).'; ''; 'print_string:'; '	ldy #0'; ''; 'print_string_loop:'; '	; Load a character from the specified pointer'; '	; and output it.'; ''; '	lda ($00),y'; '	beq print_string_done ; If 0, then exit'; '	jsr WOZMON_OUT'; ''; '	; Increment the pointer.'; ''; '	inc $00'; '	bne print_string_loop'; '	inc $01'; '	jmp print_string_loop'; ''; 'print_string_done:'; '	rts'; ''; 'init_msg:'; '	; 13 = Carriage return'; '	.byt 13, 13, "Hello, world!", 13, 13, 0'; ''};
            app.AssemblerFileBrowser = uitree(app.AssemblerEditorGridLayout);
            app.BuiltInExamplesNode = uitreenode(app.AssemblerFileBrowser);
            app.BuiltInExamplesNode.Text = 'Built-in examples';
            app.BuiltInExamplesHelloWorldNode = uitreenode(app.BuiltInExamplesNode);
            app.BuiltInExamplesHelloWorldNode.Text = 'HelloWorld.asm';
            app.LocalFilesystemNode = uitreenode(app.AssemblerFileBrowser);
            app.LocalFilesystemNode.Text = 'Local filesystem';
            app.LocalFilesystemTempNode = uitreenode(app.LocalFilesystemNode);
            app.LocalFilesystemTempNode.Text = 'Loading, please wait...';
            app.AssemblerFileBrowserLabel = uilabel(app.AssemblerEditorGridLayout);
            app.AssemblerFileBrowserLabel.Text = 'File browser';
            app.AssemblerFileStatusGridLayout = uigridlayout(app.AssemblerEditorGridLayout);
            app.AssemblerFileNameLabel = uilabel(app.AssemblerFileStatusGridLayout);
            app.AssemblerFileNameLabel.Text = 'Examples/HelloWorld.asm';
            app.AssemblerSaveFileButton = uibutton(app.AssemblerFileStatusGridLayout, 'push');
            app.AssemblerSaveFileButton.Text = 'Save file';
            app.ProfilerTab = uitab(app.TabGroup);
            app.ProfilerTab.Title = 'Profiler';
            app.ProfilerTabGridLayout = uigridlayout(app.ProfilerTab);
            app.ProfilerFigure = uiaxes(app.ProfilerTabGridLayout);
            xlabel(app.ProfilerFigure, 'Time since startup (s)')
            app.ProfilerTabControlsGridLayout = uigridlayout(app.ProfilerTabGridLayout);
            app.ResetProfilerButton = uibutton(app.ProfilerTabControlsGridLayout, 'push');
            app.ResetProfilerButton.Text = 'Reset profiler';
            app.DisplaythelastDropDownLabel = uilabel(app.ProfilerTabControlsGridLayout);
            app.DisplaythelastDropDownLabel.Text = 'Display the last';
            app.ProfilerDurationDropDown = uidropdown(app.ProfilerTabControlsGridLayout);
            app.ProfilerDurationDropDown.Items = {'5 seconds', '10 seconds', '30 seconds', '1 minute', '2 minutes', '5 minutes', '10 minutes', '30 minutes', '1 hour', 'everything'};
            app.ProfilerDurationDropDown.Value = '30 seconds';
            app.PauseProfilerButton = uibutton(app.ProfilerTabControlsGridLayout, 'push');
            app.PauseProfilerButton.Text = 'Pause profiler';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsTabContainer = uigridlayout(app.SettingsTab);
            app.SettingsSubtabGroup = uitabgroup(app.SettingsTabContainer);
            app.TerminalAppearanceTab = uitab(app.SettingsSubtabGroup);
            app.TerminalAppearanceTab.Title = 'Terminal appearance';
            app.TerminalAppearanceGridLayout = uigridlayout(app.TerminalAppearanceTab);
            app.TextColorLabel = uilabel(app.TerminalAppearanceGridLayout);
            app.TextColorLabel.Text = 'Text colour';
            app.BackColorLabel = uilabel(app.TerminalAppearanceGridLayout);
            app.BackColorLabel.Text = 'Background colour';
            app.TextColorGridLayout = uigridlayout(app.TerminalAppearanceGridLayout);
            app.TextColorSliderGridLayout = uigridlayout(app.TextColorGridLayout);
            app.GreenSliderLabel = uilabel(app.TextColorSliderGridLayout);
            app.GreenSliderLabel.Text = 'Green';
            app.TextGreenSlider = uislider(app.TextColorSliderGridLayout);
            app.TextGreenSlider.Value = 100;
            app.TextBlueSlider = uislider(app.TextColorSliderGridLayout);
            app.BlueSliderLabel = uilabel(app.TextColorSliderGridLayout);
            app.BlueSliderLabel.Text = 'Blue';
            app.RedSliderLabel = uilabel(app.TextColorSliderGridLayout);
            app.RedSliderLabel.Text = 'Red';
            app.TextRedSlider = uislider(app.TextColorSliderGridLayout);
            app.TextLamp = uilamp(app.TextColorGridLayout);
            app.BackColorGridLayout = uigridlayout(app.TerminalAppearanceGridLayout);
            app.BackColorSliderGridLayout = uigridlayout(app.BackColorGridLayout);
            app.RedSlider_2Label = uilabel(app.BackColorSliderGridLayout);
            app.RedSlider_2Label.Text = 'Red';
            app.BackRedSlider = uislider(app.BackColorSliderGridLayout);
            app.GreenSlider_2Label = uilabel(app.BackColorSliderGridLayout);
            app.GreenSlider_2Label.Text = 'Green';
            app.BackGreenSlider = uislider(app.BackColorSliderGridLayout);
            app.BlueSlider_2Label = uilabel(app.BackColorSliderGridLayout);
            app.BlueSlider_2Label.Text = 'Blue';
            app.BackBlueSlider = uislider(app.BackColorSliderGridLayout);
            app.BackLamp = uilamp(app.BackColorGridLayout);
            app.MIDISetupTab = uitab(app.SettingsSubtabGroup);
            app.MIDISetupTab.Title = 'MIDI setup';
            app.MIDISetupGridLayout = uigridlayout(app.MIDISetupTab);
            app.MIDISetupLabel = uilabel(app.MIDISetupGridLayout);
            app.MIDISetupLabel.Text = 'In order for the virtual Apple I to produce sound, it needs to be connected to a software MIDI synthesizer.';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTabGridLayout = uigridlayout(app.AboutTab);
            app.AboutLogo = uiimage(app.AboutTabGridLayout);
            app.AboutLogo.ImageSource = fullfile(pathToMLAPP, 'emulogo.jpg');
            app.AboutDescGridLayout = uigridlayout(app.AboutTabGridLayout);
            app.AboutNameLabel = uilabel(app.AboutDescGridLayout);
            app.AboutNameLabel.Text = 'A dubiously accurate Apple I emulator';
            app.AboutDescLabel = uilabel(app.AboutDescGridLayout);
            app.AboutDescLabel.Text = {'A reasonably fast Apple I emulator, assembler and debugger in MATLAB.'; ''; '(logo source: Stable Diffusion + my l33t GIMP skills)'};
            app.AboutLicenseLogo = uiimage(app.AboutTabGridLayout);
            app.AboutLicenseLogo.ImageSource = fullfile(pathToMLAPP, 'gnu.svg');
            app.GridLayout7 = uigridlayout(app.AboutTabGridLayout);
            app.AboutGPLButton = uibutton(app.GridLayout7, 'push');
            app.AboutGPLButton.ButtonPushedFcn = createCallbackFcn(app, @AboutGPLButtonPushed, true);
            app.AboutGPLButton.Text = 'Read the GPL v3.0 license';
            app.AboutMATLABLicenseButton = uibutton(app.GridLayout7, 'push');
            app.AboutMATLABLicenseButton.ButtonPushedFcn = createCallbackFcn(app, @AboutMATLABLicenseButtonPushed, true);
            app.AboutMATLABLicenseButton.Text = 'Read the MATLAB license';
            app.AboutLicenseLabel = uilabel(app.AboutTabGridLayout);
            app.AboutLicenseLabel.Text = 'If you ignore the absolutely proprietary MATLAB runtime binary, this software is considered free software, licensed under the GNU General Public License v3.0.';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @EmulatorStart)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end